classdef F2UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Down         matlab.ui.control.Button
        Up           matlab.ui.control.Button
        B            matlab.ui.control.Button
        LD           matlab.ui.control.TextArea
        RD           matlab.ui.control.TextArea
        Floor2Label  matlab.ui.control.Label
        L_State      matlab.ui.control.TextArea
        R_State      matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Ctrl % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: B
        function BButtonPushed(app, event)
            app.B.BackgroundColor = [0.44,0.9,0.8];
            app.Ctrl.BRequest(2);
        end

        % Button pushed function: Up
        function UpButtonPushed(app, event)
            app.Up.BackgroundColor = [0.44,0.9,0.8];
            app.Ctrl.addFloorRequest(2,1);
        end

        % Button pushed function: Down
        function DownButtonPushed(app, event)
            app.Down.BackgroundColor = [0.44,0.9,0.8];
            app.Ctrl.addFloorRequest(2,-1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Down = uibutton(app.UIFigure, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.Down.Text = '↓';
            app.Up = uibutton(app.UIFigure, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.Up.Text = '↑';
            app.B = uibutton(app.UIFigure, 'push');
            app.B.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.B.Text = 'B';
            app.LD = uitextarea(app.UIFigure);
            app.RD = uitextarea(app.UIFigure);
            app.Floor2Label = uilabel(app.UIFigure);
            app.Floor2Label.Text = 'Floor 2';
            app.L_State = uitextarea(app.UIFigure);
            app.R_State = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F2UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end