classdef EleVersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Floor4               matlab.ui.control.Label
        Floor3               matlab.ui.control.Label
        Floor2               matlab.ui.control.Label
        Floor1               matlab.ui.control.Label
        RightDisplayer       matlab.ui.control.TextArea
        LeftDisplayer        matlab.ui.control.TextArea
        LeftElevator         matlab.ui.control.Image
        RightElevator        matlab.ui.control.Image
        VelocityLabel        matlab.ui.control.Label
        L_v                  matlab.ui.control.TextArea
        AccelerationLabel    matlab.ui.control.Label
        L_a                  matlab.ui.control.TextArea
        VelocityLabel_2      matlab.ui.control.Label
        R_v                  matlab.ui.control.TextArea
        AccelerationLabel_2  matlab.ui.control.Label
        R_a                  matlab.ui.control.TextArea
        F3Label              matlab.ui.control.Label
        F2Label              matlab.ui.control.Label
        F1Label              matlab.ui.control.Label
        BaseLabel            matlab.ui.control.Label
        LLD                  matlab.ui.control.Image
        LRD                  matlab.ui.control.Image
        RLD                  matlab.ui.control.Image
        RRD                  matlab.ui.control.Image
        HeightLabel          matlab.ui.control.Label
        L_height             matlab.ui.control.TextArea
        HeightLabel_2        matlab.ui.control.Label
        R_height             matlab.ui.control.TextArea
        FloorLabel           matlab.ui.control.Label
        FloorLabel_2         matlab.ui.control.Label
        DoorStateLabel       matlab.ui.control.Label
        L_DoorState          matlab.ui.control.TextArea
        DoorStateLabel_2     matlab.ui.control.Label
        R_DoorState          matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Ctrl
    end
    
    methods (Access = public)
        
        function LoadUI(app)
            app.LeftElevator.Position(2) = app.Ctrl.elevators(1).y*80;
            app.LLD.Position(2) = app.LeftElevator.Position(2)+12;
            app.LRD.Position(2) = app.LeftElevator.Position(2)+12;
            app.LLD.Position(3) = app.Ctrl.elevators(1).doorSize;
            app.LRD.Position(3) = app.Ctrl.elevators(1).doorSize;
            app.LRD.Position(1) = 385-app.Ctrl.elevators(1).doorSize;
            app.RightElevator.Position(2) = app.Ctrl.elevators(2).y*80;
            app.RLD.Position(2) = app.RightElevator.Position(2)+12;
            app.RRD.Position(2) = app.RightElevator.Position(2)+12;
            app.RLD.Position(3) = app.Ctrl.elevators(2).doorSize;
            app.RRD.Position(3) = app.Ctrl.elevators(2).doorSize;
            app.RRD.Position(1) = 689-app.Ctrl.elevators(2).doorSize;
            app.R_a.Value = sprintf('%s',string(app.Ctrl.elevators(2).adir*app.Ctrl.elevators(2).a));
            app.R_v.Value = sprintf('%s',string(app.Ctrl.elevators(2).v));
            app.R_height.Value = sprintf('%s',string(app.Ctrl.elevators(2).y-3));
            app.R_DoorState.Value = sprintf('%s',string(app.Ctrl.elevators(2).doorState));
            app.L_a.Value = sprintf('%s',string(app.Ctrl.elevators(1).adir*app.Ctrl.elevators(1).a));
            app.L_v.Value = sprintf('%s',string(app.Ctrl.elevators(1).v));
            app.L_height.Value = sprintf('%s',string(app.Ctrl.elevators(1).y-3));
            app.L_DoorState.Value = sprintf('%s',string(app.Ctrl.elevators(1).doorState));
            if app.Ctrl.elevators(1).y>=0 && app.Ctrl.elevators(1).y < 1.5
                app.LeftDisplayer.Value = 'B';
                
            elseif app.Ctrl.elevators(1).y>= 1.5 && app.Ctrl.elevators(1).y < 4.5
                app.LeftDisplayer.Value = '1';
                
            elseif app.Ctrl.elevators(1).y>= 4.5 && app.Ctrl.elevators(1).y < 7.5
                app.LeftDisplayer.Value = '2';
                
            elseif app.Ctrl.elevators(1).y>=7.5 && app.Ctrl.elevators(1).y <=9
                app.LeftDisplayer.Value = '3';
                
            end
            if app.Ctrl.elevators(2).y>= 3 && app.Ctrl.elevators(2).y < 4.5
                app.RightDisplayer.Value = '1';
                
            elseif app.Ctrl.elevators(2).y>= 4.5 && app.Ctrl.elevators(2).y < 7.5
                app.RightDisplayer.Value = '2';
               
            elseif app.Ctrl.elevators(2).y>=7.5 && app.Ctrl.elevators(2).y <=9
                app.RightDisplayer.Value = '3';
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Ctrl.delete();
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Floor4 = uilabel(app.UIFigure);
            app.Floor4.Text = '';
            app.Floor3 = uilabel(app.UIFigure);
            app.Floor3.Text = '';
            app.Floor2 = uilabel(app.UIFigure);
            app.Floor2.Text = '';
            app.Floor1 = uilabel(app.UIFigure);
            app.Floor1.Text = '';
            app.RightDisplayer = uitextarea(app.UIFigure);
            app.LeftDisplayer = uitextarea(app.UIFigure);
            app.LeftElevator = uiimage(app.UIFigure);
            app.LeftElevator.ImageSource = 'DoorFullyOpened.png';
            app.RightElevator = uiimage(app.UIFigure);
            app.RightElevator.ImageSource = 'DoorFullyOpened.png';
            app.VelocityLabel = uilabel(app.UIFigure);
            app.VelocityLabel.Text = 'Velocity';
            app.L_v = uitextarea(app.UIFigure);
            app.AccelerationLabel = uilabel(app.UIFigure);
            app.AccelerationLabel.Text = 'Acceleration';
            app.L_a = uitextarea(app.UIFigure);
            app.VelocityLabel_2 = uilabel(app.UIFigure);
            app.VelocityLabel_2.Text = 'Velocity';
            app.R_v = uitextarea(app.UIFigure);
            app.AccelerationLabel_2 = uilabel(app.UIFigure);
            app.AccelerationLabel_2.Text = 'Acceleration';
            app.R_a = uitextarea(app.UIFigure);
            app.F3Label = uilabel(app.UIFigure);
            app.F3Label.Text = 'F3';
            app.F2Label = uilabel(app.UIFigure);
            app.F2Label.Text = 'F2';
            app.F1Label = uilabel(app.UIFigure);
            app.F1Label.Text = 'F1';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'Base';
            app.LLD = uiimage(app.UIFigure);
            app.LLD.ImageSource = 'DoorLeft.png';
            app.LRD = uiimage(app.UIFigure);
            app.LRD.ImageSource = 'DoorRight.png';
            app.RLD = uiimage(app.UIFigure);
            app.RLD.ImageSource = 'DoorLeft.png';
            app.RRD = uiimage(app.UIFigure);
            app.RRD.ImageSource = 'DoorRight.png';
            app.HeightLabel = uilabel(app.UIFigure);
            app.HeightLabel.Text = 'Height';
            app.L_height = uitextarea(app.UIFigure);
            app.HeightLabel_2 = uilabel(app.UIFigure);
            app.HeightLabel_2.Text = 'Height';
            app.R_height = uitextarea(app.UIFigure);
            app.FloorLabel = uilabel(app.UIFigure);
            app.FloorLabel.Text = 'Floor';
            app.FloorLabel_2 = uilabel(app.UIFigure);
            app.FloorLabel_2.Text = 'Floor';
            app.DoorStateLabel = uilabel(app.UIFigure);
            app.DoorStateLabel.Text = 'DoorState';
            app.L_DoorState = uitextarea(app.UIFigure);
            app.DoorStateLabel_2 = uilabel(app.UIFigure);
            app.DoorStateLabel_2.Text = 'DoorState';
            app.R_DoorState = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EleVersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end