classdef LctrlUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        L_C            matlab.ui.control.Button
        L_O            matlab.ui.control.Button
        L_1            matlab.ui.control.Button
        L_2            matlab.ui.control.Button
        L_3            matlab.ui.control.Button
        L_B            matlab.ui.control.Button
        LeftDisplayer  matlab.ui.control.TextArea
        LeftLabel      matlab.ui.control.Label
        State          matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Ctrl % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: L_O
        function L_OButtonPushed(app, event)
            if app.Ctrl.elevators(1).v == 0
                app.Ctrl.elevators(1).doorState = 'Opening';
            end
        end

        % Button pushed function: L_C
        function L_CButtonPushed(app, event)
            if strcmp(app.Ctrl.elevators(1).doorState,'Opened')
                app.Ctrl.elevators(1).doorState = 'Closing';
            end
        end

        % Button pushed function: L_1
        function L_1ButtonPushed(app, event)
            app.L_1.BackgroundColor = [0.44,0.9,0.8];
            app.Ctrl.addElevatorRequest(1, 1);
        end

        % Button pushed function: L_2
        function L_2ButtonPushed(app, event)
            app.L_2.BackgroundColor = [0.44,0.9,0.8];
            app.Ctrl.addElevatorRequest(1, 2);
        end

        % Button pushed function: L_3
        function L_3ButtonPushed(app, event)
           app.L_3.BackgroundColor = [0.44,0.9,0.8];
            app.Ctrl.addElevatorRequest(1, 3);
        end

        % Button pushed function: L_B
        function L_BButtonPushed(app, event)
            app.L_B.BackgroundColor = [0.44,0.9,0.8];
            app.Ctrl.addElevatorRequest(1, 0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.L_C = uibutton(app.UIFigure, 'push');
            app.L_C.ButtonPushedFcn = createCallbackFcn(app, @L_CButtonPushed, true);
            app.L_C.Text = '> <';
            app.L_O = uibutton(app.UIFigure, 'push');
            app.L_O.ButtonPushedFcn = createCallbackFcn(app, @L_OButtonPushed, true);
            app.L_O.Text = '< >';
            app.L_1 = uibutton(app.UIFigure, 'push');
            app.L_1.ButtonPushedFcn = createCallbackFcn(app, @L_1ButtonPushed, true);
            app.L_1.Text = {'1'; ''};
            app.L_2 = uibutton(app.UIFigure, 'push');
            app.L_2.ButtonPushedFcn = createCallbackFcn(app, @L_2ButtonPushed, true);
            app.L_2.Text = '2';
            app.L_3 = uibutton(app.UIFigure, 'push');
            app.L_3.ButtonPushedFcn = createCallbackFcn(app, @L_3ButtonPushed, true);
            app.L_3.Text = '3';
            app.L_B = uibutton(app.UIFigure, 'push');
            app.L_B.ButtonPushedFcn = createCallbackFcn(app, @L_BButtonPushed, true);
            app.L_B.Text = 'B';
            app.LeftDisplayer = uitextarea(app.UIFigure);
            app.LeftLabel = uilabel(app.UIFigure);
            app.LeftLabel.Text = 'Left';
            app.State = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LctrlUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end