classdef RctrlUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        R_C             matlab.ui.control.Button
        R_O             matlab.ui.control.Button
        R_1             matlab.ui.control.Button
        R_2             matlab.ui.control.Button
        R_3             matlab.ui.control.Button
        RightDisplayer  matlab.ui.control.TextArea
        RightLabel      matlab.ui.control.Label
        State           matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Ctrl % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: R_3
        function R_3ButtonPushed(app, event)
            app.R_3.BackgroundColor = [0.44,0.9,0.8];
            app.Ctrl.addElevatorRequest(2, 3);
        end

        % Button pushed function: R_2
        function R_2ButtonPushed(app, event)
            app.R_2.BackgroundColor = [0.44,0.9,0.8];
            app.Ctrl.addElevatorRequest(2, 2);
        end

        % Button pushed function: R_1
        function R_1ButtonPushed(app, event)
            app.R_1.BackgroundColor = [0.44,0.9,0.8];
            app.Ctrl.addElevatorRequest(2, 1);
        end

        % Button pushed function: R_O
        function R_OButtonPushed(app, event)
           if app.Ctrl.elevators(2).v == 0
                app.Ctrl.elevators(2).doorState = 'Opening';
           end
        end

        % Button pushed function: R_C
        function R_CButtonPushed(app, event)
           if strcmp(app.Ctrl.elevators(2).doorState,'Opened')
                app.Ctrl.elevators(2).doorState = 'Closing';
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.R_C = uibutton(app.UIFigure, 'push');
            app.R_C.ButtonPushedFcn = createCallbackFcn(app, @R_CButtonPushed, true);
            app.R_C.Text = '> <';
            app.R_O = uibutton(app.UIFigure, 'push');
            app.R_O.ButtonPushedFcn = createCallbackFcn(app, @R_OButtonPushed, true);
            app.R_O.Text = '< >';
            app.R_1 = uibutton(app.UIFigure, 'push');
            app.R_1.ButtonPushedFcn = createCallbackFcn(app, @R_1ButtonPushed, true);
            app.R_1.Text = {'1'; ''};
            app.R_2 = uibutton(app.UIFigure, 'push');
            app.R_2.ButtonPushedFcn = createCallbackFcn(app, @R_2ButtonPushed, true);
            app.R_2.Text = '2';
            app.R_3 = uibutton(app.UIFigure, 'push');
            app.R_3.ButtonPushedFcn = createCallbackFcn(app, @R_3ButtonPushed, true);
            app.R_3.Text = '3';
            app.RightDisplayer = uitextarea(app.UIFigure);
            app.RightLabel = uilabel(app.UIFigure);
            app.RightLabel.Text = 'Right';
            app.State = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RctrlUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end