classdef MaintainerUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        MerchandiseStatusTab    matlab.ui.container.Tab
        MerchandiseTable        matlab.ui.control.Table
        MoneyBoxStatusTab       matlab.ui.container.Tab
        MoneyTable              matlab.ui.control.Table
        PaymentRecordTab        matlab.ui.container.Tab
        OrderRecordTable        matlab.ui.control.Table
        LogPanel                matlab.ui.container.Panel
        Log                     matlab.ui.control.Label
        AddPanel                matlab.ui.container.Panel
        ItemNameEditFieldLabel  matlab.ui.control.Label
        AddEditField            matlab.ui.control.EditField
        AddButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        Controller
    end
    
    methods (Access = public)
        
        function Init(app)
            app.UIFigure.Name = 'Mantainer Backend';
            app.appendLog('Auto Vending Machine ver2.2 is running.');
            app.LoadMerchandise;
            app.LoadMoney;
        end
        % Log display function
        function appendLog(app, log)
            if isempty(log)
                return;
            end
            app.Log.Text=[sprintf('[%s] %s\n', datestr(now,'mm-dd HH:MM:SS'), log) app.Log.Text];
        end
        % Table display function
        function LoadMoney(app)
            app.appendLog(app.Controller.moneyBox.alertMessage);
            money = app.Controller.getMoney;
            moneyName = {'0.5￥Coin', '1￥Coin', '1￥Cash', '5￥Cash', '10￥Cash', '20￥Cash'};
            tempdata={};
            for i=1:length(money)
                tempdata = [tempdata; {moneyName{i}, sprintf('%d', money(i))}];
            end
            app.MoneyTable.Data = tempdata;
        end
        
        function LoadMerchandise(app)
            app.appendLog(app.Controller.merchandiseDB.alertMessage);
            merchandises = app.Controller.merchandiseDB.retrieve;
            tempdata = {};
            for i = 1 : length(merchandises)
                tempdata = [tempdata; {merchandises(i).name, sprintf('%.1f', merchandises(i).price), sprintf('%d', merchandises(i).quantity)}];
            end
            app.MerchandiseTable.Data = tempdata;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            name = app.AddEditField.Value;
            if isempty(name)
                app.appendLog('Please input merchandise name.');
                return;
            end
            merchandise = app.Controller.merchandiseDB.getMerchandise(name);
            if ~isempty(merchandise)
                app.appendLog('Merchandise name already exists!');
                return;
            end
            app.appendLog(sprintf('%s has been added successfully!', name));
            app.Controller.addMerchandise(name);
        end

        % Cell edit callback: MerchandiseTable
        function MerchandiseTableCellEdit(app, event)
            indices = event.Indices;
            [newData, res] = str2num(event.NewData);
            if res == 0
                app.appendLog('Input illegal!(should be numeric)');
                app.LoadMerchandise;
                return;
            end
            merchandise = app.MerchandiseTable.Data{indices(:, 1), 1};
            price = str2num(app.MerchandiseTable.Data{indices(:, 1), 2});
            quantity = str2num(app.MerchandiseTable.Data{indices(:, 1), 3});
            switch indices(:, 2)
                % Edit Price
                case 2
                    if (rem(price, 0.5) ~= 0 || price < 0.5)
                        app.appendLog('Input price illegal!(should be able to pay)');
                        app.LoadMerchandise;
                        return;
                    end
                    app.Controller.updateMerchandise(merchandise, newData, quantity);
                    
                % Edit Quantity
                case 3
                    if (rem(quantity, 1) ~= 0 || quantity < 0)
                        app.appendLog('Input quantity illegal!(should be integer>=0)');
                        app.LoadMerchandise;
                        return;
                    end
                    if newData > app.Controller.merchandiseDB.merchandiseCapacity
                        app.appendLog('Exceed maximum capacity!');
                        app.LoadMerchandise;
                        return;
                    end
                    app.Controller.updateMerchandise(merchandise, price, newData);
            end
        end

        % Cell edit callback: MoneyTable
        function MoneyTableCellEdit(app, event)
            indices = event.Indices;
            [newData, res] = str2num(event.NewData);
            % Illegal input
            if res == 0
                app.appendLog('Input illegal!(should be numeric)');
                app.LoadMoney;
                return;
            end
            if rem(newData, 1) ~= 0 || newData < 0
                app.appendLog('Input quantity illegal!(should be integer>=0)');
                app.LoadMoney;
                return;
            end
            if (indices(:, 1) <= 2 && newData > app.Controller.moneyBox.coinCapacity) || (indices(:, 1) > 2 && newData > app.Controller.moneyBox.cashCapacity)
                app.appendLog('Exceed maximum capacity!');
                app.LoadMoney;
                return;
            end
            money = app.Controller.getMoney;
            money(indices(:, 1)) = newData;
            if indices(:, 1) <= 2
                app.Controller.updateCoins(money(1:2));
            else
                app.Controller.updateCash(money(3:6));
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab
                case app.MerchandiseStatusTab
                    app.AddPanel.Visible = 1;
                otherwise
                    app.AddPanel.Visible = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MerchandiseStatusTab = uitab(app.TabGroup);
            app.MerchandiseStatusTab.Title = 'Merchandise Status';
            app.MerchandiseTable = uitable(app.MerchandiseStatusTab);
            app.MerchandiseTable.ColumnName = {'Merchandise'; 'Price'; 'Quantity'};
            app.MerchandiseTable.RowName = {};
            app.MerchandiseTable.ColumnSortable = [true true true];
            app.MerchandiseTable.ColumnEditable = [false true true];
            app.MerchandiseTable.CellEditCallback = createCallbackFcn(app, @MerchandiseTableCellEdit, true);
            app.MoneyBoxStatusTab = uitab(app.TabGroup);
            app.MoneyBoxStatusTab.Title = 'Money Box Status';
            app.MoneyTable = uitable(app.MoneyBoxStatusTab);
            app.MoneyTable.ColumnName = {'Money'; 'Quantity'};
            app.MoneyTable.RowName = {};
            app.MoneyTable.ColumnEditable = [false true];
            app.MoneyTable.CellEditCallback = createCallbackFcn(app, @MoneyTableCellEdit, true);
            app.PaymentRecordTab = uitab(app.TabGroup);
            app.PaymentRecordTab.Title = 'Payment Record';
            app.OrderRecordTable = uitable(app.PaymentRecordTab);
            app.OrderRecordTable.ColumnName = {'Date'; 'Merchandise'; 'Price'; 'Status'};
            app.OrderRecordTable.RowName = {};
            app.LogPanel = uipanel(app.UIFigure);
            app.LogPanel.Title = 'Message';
            app.Log = uilabel(app.LogPanel);
            app.Log.Text = '';
            app.AddPanel = uipanel(app.LogPanel);
            app.AddPanel.Title = 'Add Panel';
            app.ItemNameEditFieldLabel = uilabel(app.AddPanel);
            app.ItemNameEditFieldLabel.Text = 'Item Name';
            app.AddEditField = uieditfield(app.AddPanel, 'text');
            app.AddButton = uibutton(app.AddPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaintainerUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end