classdef UserUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MerchandiseTable      matlab.ui.control.Table
        MessagePanel          matlab.ui.container.Panel
        Message               matlab.ui.control.Label
        ReturnButton          matlab.ui.control.Button
        CoinSelection         matlab.ui.control.DropDown
        CoinInsertButton      matlab.ui.control.Button
        MerchandiseSelection  matlab.ui.control.DropDown
        ConfirmButton         matlab.ui.control.Button
        CashSelection         matlab.ui.control.DropDown
        CashInsertButton      matlab.ui.control.Button
    end

    
   properties (Access = public)
        Controller
        paymentMessage
        % SelectedMerchandise app.MerchandiseSelection.UserData
        % InsertedCoins app.CoinInsertButton.UserData
        % InsertedCashs app.CashInsertButton.UserData
   end
    
    methods (Access = public)
        function Init(app)
            app.MerchandiseSelection.UserData = app.Controller.merchandiseDB.getMerchandise("Coke");
            app.LoadMerchandise;
        end
        % Message display function
        function displayMessage(app, Message)
            app.Message.Text = Message;
        end
        function appendMessage(app, Message)
            if isempty(Message)
                return;
            end
            if isempty(app.Message.Text)
                app.Message.Text = Message;
            else
                app.Message.Text = [sprintf('%s\n', app.Message.Text) Message];
            end
        end
        function displayWelcomeMessage(app, pauseTime)
            app.MerchandiseSelection.Enable=0;
            app.CoinSelection.Enable=0;
            app.CashSelection.Enable=0;
            app.CoinInsertButton.Enable=0;
            app.CashInsertButton.Enable=0;
            app.ReturnButton.Enable = 0;
            app.ConfirmButton.Enable = 0;
            pause(pauseTime);
            app.displayMessage('Welcome! Choose what to purchase below ↓');
            app.MerchandiseSelection.Enable=1;
            app.ConfirmButton.Enable=1;
        end
        % Payment display function
        function displayPaymentMessage(app, restTime)
            merchandise = app.MerchandiseSelection.UserData;
            app.displayMessage(sprintf('Merchandise:%s', merchandise.name));
            app.appendMessage(sprintf('Price:￥%.1f', merchandise.price));
            app.appendMessage('---------');
            app.appendMessage(sprintf('You''ve inserted %.1f￥.', sum([0.5 1 1 5 10 20].*[app.CoinInsertButton.UserData app.CashInsertButton.UserData])));
            if (app.paymentMessage)
                app.appendMessage(app.paymentMessage);
            end
            if (app.ReturnButton.UserData(7) > 0)
                app.appendMessage(sprintf('! %d unrecognizable detected', app.ReturnButton.UserData(7)));
            end
            app.appendMessage(sprintf('Order cancel in %ds...', restTime));
            app.appendMessage('Please pay in time.');
        end
        function displaySuccessPayment(app, changeMoney)
            app.displayMessage('Payment success!');
            app.ConfirmButton.Enable=1;
            app.CoinSelection.Enable=0;
            app.CoinInsertButton.Enable=0;
            app.CashSelection.Enable=0;
            app.CashInsertButton.Enable=0;
            app.CoinInsertButton.UserData = zeros(1, 2);
            app.CashInsertButton.UserData = zeros(1, 4);
            change = sum([0.5 1 1 5 10 20].*changeMoney);
            if change > 0 
                app.appendMessage(sprintf('Get your %s and\n%.1f￥ change below ↓', app.MerchandiseSelection.Value, change));
                app.ReturnButton.UserData(1:6) = app.ReturnButton.UserData(1:6) + changeMoney;
                app.ReturnButton.Enable=1;
            else
                app.appendMessage(sprintf('Get your %s below ↓', app.MerchandiseSelection.Value));
                app.displayWelcomeMessage(4);
                app.ReturnButton.Enable = sum(app.ReturnButton.UserData) > 0;  
            end
        end
        function displayFailedPayment(app)
            app.displayMessage(sprintf('Not enough coin!\nPlease contact maintainer.'));
            app.displayWelcomeMessage(4);
            app.ReturnButton.Enable=1;
        end
        function displayTimeOutPayment(app)
            app.displayMessage(sprintf('Payment Time-out\nPlease try again.\n'));
            tmp = app.ReturnButton.Enable;
            app.displayWelcomeMessage(2);
            app.ReturnButton.Enable = tmp;
        end
        % Table display function
        function LoadMerchandise(app)
            merchandises = app.Controller.merchandiseDB.retrieve;
            tempdata = {};
            tempitems = {};
            for i = 1 : length(merchandises)
                price = sprintf('%.1f', merchandises(i).price);
                if merchandises(i).quantity > 0
                    tempdata = [tempdata; {merchandises(i).name, price, 'Available'}];
                    tempitems = [tempitems; merchandises(i).name];
                else
                    tempdata = [tempdata; {merchandises(i).name, price, 'Out of stock'}];
                end
            end
            app.MerchandiseSelection.Items = tempitems;
            app.MerchandiseTable.Data = tempdata;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Auto Vending Machine';
            app.CoinInsertButton.UserData=zeros(1,2);
            app.CashInsertButton.UserData=zeros(1,4);
            app.ReturnButton.UserData=zeros(1,7);
        end

        % Value changed function: MerchandiseSelection
        function MerchandiseSelectionValueChanged(app, event)
            app.Controller.cancelPayment;
            app.ConfirmButton.Enable=1;
            app.CoinSelection.Enable=0;
            app.CoinInsertButton.Enable=0;
            app.CashSelection.Enable=0;
            app.CashInsertButton.Enable=0;
            merchandise = app.Controller.merchandiseDB.getMerchandise(app.MerchandiseSelection.Value);
            app.MerchandiseSelection.UserData = merchandise;
            app.displayMessage(sprintf('Merchandise:%s', merchandise.name));
            app.appendMessage(sprintf('Price:￥%.1f', merchandise.price));
            app.appendMessage('---------');
            app.appendMessage('Waiting for payment...');
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            
            app.paymentMessage = '';
            name = app.MerchandiseSelection.Value;
            merchandise = app.Controller.merchandiseDB.getMerchandise(name);
            app.Controller.createPayment(merchandise);
            app.Controller.payment.updatePaidMoney(sum([0.5 1 1 5 10 20].*[app.CoinInsertButton.UserData app.CashInsertButton.UserData]));
            app.ConfirmButton.Enable=0;
            app.CoinSelection.Enable=1;
            app.CashSelection.Enable=1;
            app.CoinInsertButton.Enable=1;
            app.CashInsertButton.Enable=1;
        end

        % Button pushed function: CoinInsertButton
        function CoinInsertButtonPushed(app, event)
           app.ReturnButton.Enable=1;
           if strcmp(app.CoinSelection.Value,'Fake Coin')
               app.ReturnButton.UserData(7) = app.ReturnButton.UserData(7) + 1;
               return;
           end
           insertedCoins = zeros(1, 2);
           switch app.CoinSelection.Value
               case '0.5￥'
                   insertedCoins(1) = 1;
               case '1￥'
                   insertedCoins(2) = 1;
           end
           res = app.Controller.addCoins(insertedCoins);
           if res
               app.CoinInsertButton.UserData = app.CoinInsertButton.UserData + insertedCoins;
               app.Controller.payment.updatePaidMoney(sum([0.5 1].*insertedCoins));
           else
               app.ReturnButton.UserData(1:2) = app.ReturnButton.UserData(1:2) + insertedCoins;
               app.paymentMessage='! Money box full, please contact maintainer';
           end
        end

        % Button pushed function: CashInsertButton
        function CashInsertButtonPushed(app, event)
           app.ReturnButton.Enable=1;
           if strcmp(app.CashSelection.Value,'Fake Cash')
               app.ReturnButton.UserData(7) = app.ReturnButton.UserData(7) + 1;
               return;
           end
           insertedCash = zeros(1, 4);
           switch app.CashSelection.Value
               case '1￥'
                   insertedCash(1) = 1;
               case '5￥'
                   insertedCash(2) = 1;
               case '10￥'
                   insertedCash(3) = 1;
               case '20￥'
                   insertedCash(4) = 1;
           end
           res = app.Controller.addCash(insertedCash);
           if res
               app.CashInsertButton.UserData = app.CashInsertButton.UserData + insertedCash;
               app.Controller.payment.updatePaidMoney(sum([1 5 10 20].*insertedCash));
           else
               app.ReturnButton.UserData(3:6) = app.ReturnButton.UserData(3:6) + insertedCash;
               app.paymentMessage='!Money box full, please contact maintainer.';
           end
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            app.ReturnButton.Enable = 0;
            app.CoinSelection.Enable=0;
            app.CoinInsertButton.Enable=0;
            app.CashSelection.Enable=0;
            app.CashInsertButton.Enable=0;
            insertedCoins = app.CoinInsertButton.UserData;
            insertedCash = app.CashInsertButton.UserData;
            returnMoney = [insertedCoins insertedCash 0];
            returnMoney = returnMoney + app.ReturnButton.UserData;
            returnAmount=sum([0.5, 1, 1, 5, 10, 20, 0].*returnMoney);
            app.Controller.cancelPayment;
            if returnMoney(7) > 0
                app.displayMessage(sprintf('Get your %.1f￥ and %d unrecognizable money below ↓', returnAmount, returnMoney(7)));
            else
                app.displayMessage(sprintf('Get your %.1f￥ below ↓', returnAmount));
            end
            app.appendMessage('---------');
            moneyName = {'0.5￥Coin', '1￥Coin', '1￥Cash', '5￥Cash', '10￥Cash', '20￥Cash', 'Unrecognizable'};
            for i=1:length(moneyName)
                if returnMoney(i) > 0
                    app.appendMessage(sprintf('%s*%d', moneyName{i}, returnMoney(i)));
                end
            end
            app.Controller.addCoins(-insertedCoins);
            app.Controller.addCash(-insertedCash);
            app.CoinInsertButton.UserData = zeros(1,2);
            app.CashInsertButton.UserData = zeros(1,4);
            app.ReturnButton.UserData = zeros(1,7);
            app.displayWelcomeMessage(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MerchandiseTable = uitable(app.UIFigure);
            app.MerchandiseTable.ColumnName = {'Merchandise'; 'Price'; 'Availability'};
            app.MerchandiseTable.RowName = {};
            app.MerchandiseTable.ColumnSortable = [true true true];
            app.MerchandiseTable.ColumnEditable = [false false false];
            app.MessagePanel = uipanel(app.UIFigure);
            app.MessagePanel.Title = 'Message';
            app.Message = uilabel(app.MessagePanel);
            app.Message.Text = 'Welcome! Choose what to purchase below ↓';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Enable = 'off';
            app.ReturnButton.Text = 'Return Money';
            app.CoinSelection = uidropdown(app.UIFigure);
            app.CoinSelection.Items = {'0.5￥', '1￥', 'Fake Coin'};
            app.CoinSelection.Enable = 'off';
            app.CoinSelection.Value = '1￥';
            app.CoinInsertButton = uibutton(app.UIFigure, 'push');
            app.CoinInsertButton.ButtonPushedFcn = createCallbackFcn(app, @CoinInsertButtonPushed, true);
            app.CoinInsertButton.Enable = 'off';
            app.CoinInsertButton.Text = 'Insert Coin';
            app.MerchandiseSelection = uidropdown(app.UIFigure);
            app.MerchandiseSelection.Items = {'Coke', 'Fanta', 'WangMilk'};
            app.MerchandiseSelection.ValueChangedFcn = createCallbackFcn(app, @MerchandiseSelectionValueChanged, true);
            app.MerchandiseSelection.Value = 'Coke';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Purchase';
            app.CashSelection = uidropdown(app.UIFigure);
            app.CashSelection.Items = {'1￥', '5￥', '10￥', '20￥', 'Fake Cash'};
            app.CashSelection.Enable = 'off';
            app.CashSelection.Value = '5￥';
            app.CashInsertButton = uibutton(app.UIFigure, 'push');
            app.CashInsertButton.ButtonPushedFcn = createCallbackFcn(app, @CashInsertButtonPushed, true);
            app.CashInsertButton.Enable = 'off';
            app.CashInsertButton.Text = 'Insert Cash';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end