classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Toolbar                   matlab.ui.container.Toolbar
        ClearButton               matlab.ui.container.toolbar.PushTool
        ResetButton               matlab.ui.container.toolbar.PushTool
        BackwardButton            matlab.ui.container.toolbar.PushTool
        set5xButton               matlab.ui.container.toolbar.PushTool
        set1xButton               matlab.ui.container.toolbar.PushTool
        set15xButton              matlab.ui.container.toolbar.PushTool
        set2xButton               matlab.ui.container.toolbar.PushTool
        ForwardButton             matlab.ui.container.toolbar.PushTool
        DisplayButton             matlab.ui.container.toolbar.PushTool
        TabGroup                  matlab.ui.container.TabGroup
        OverviewTab               matlab.ui.container.Tab
        Image                     matlab.ui.control.Image
        OverviewInfoPanel         matlab.ui.container.Panel
        CurrentTimeMessage        matlab.ui.control.Label
        LocomotivesMessage        matlab.ui.control.Label
        StationsMessage           matlab.ui.control.Label
        DisplaySpeedMessage       matlab.ui.control.Label
        WarningMessage            matlab.ui.control.Label
        TimeTable                 matlab.ui.control.Table
        ImportButton              matlab.ui.control.Button
        ScheduleDropDown          matlab.ui.control.DropDown
        S1Switch                  matlab.ui.control.Image
        S2Switch                  matlab.ui.control.Image
        S3Switch                  matlab.ui.control.Image
        S4Switch                  matlab.ui.control.Image
        S5Switch                  matlab.ui.control.Image
        LocomotiveHUDTab          matlab.ui.container.Tab
        PageDropDown              matlab.ui.control.DropDown
        StatusSwitchContextMenu   matlab.ui.container.ContextMenu
        TrainName                 matlab.ui.container.Menu
        SetMaxSpeed               matlab.ui.container.Menu
        SetCruising               matlab.ui.container.Menu
        StopAtNextStation         matlab.ui.container.Menu
        StationSwitchContextMenu  matlab.ui.container.ContextMenu
        StationName               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Controller
        StationLamps
        StationLabels
        Icons
        Labels
        LocomotivePanels
        VelocityGauges
        StatusLamps
        StatusLabels
        NameLabels
        XLabels
        VLabels
        ALabels
        RefreshRate
        SwitchTo
    end
    
    
    methods (Access = public)
 
        function loadUI(app) 
            locomotives = app.Controller.getLocomotives;
            stations = app.Controller.getStations;
            time = app.Controller.getTime;
            % Load Information
            app.CurrentTimeMessage.Text = sprintf('Current Time: %.1fs', time);
            app.LocomotivesMessage.Text = sprintf('%d Locomotives in Control', length(locomotives));
            tempdata = {};
            for i = 1 : length(locomotives)
                scheduleArrival = locomotives(i).scheduleArrival;
                scheduleDeparture = locomotives(i).scheduleDeparture;
                arrivalTime = locomotives(i).arrivalTime;
                name = locomotives(i).name;
                % Load Table
                delayTime = [-1 -1 -1 -1 -1 -1];
                D = {'', '', '', '', '', ''};
                S = {'', '', '', '', '', ''};
                for j = 1:6 
                    if scheduleArrival(j+1) ~= -1
                        S{j} = sprintf('%d/%d', scheduleArrival(j+1), scheduleDeparture(j+1));
                        if arrivalTime(j+1) ~= -1
                            delayTime(j) = arrivalTime(j+1) - scheduleArrival(j+1);
                            if delayTime(j) < 0
                                delayTime(j) = 0;
                            end
                            D{j} = sprintf('%d', floor(delayTime(j)));
                        else
                            D{j} = '-';
                        end
                    end
                end
                if locomotives(i).stationID ~= -1
                    posInfo = sprintf('%s', stations(locomotives(i).stationID+1).name);
                else
                    pos = app.Controller.getPosition(locomotives(i));
                    posInfo = sprintf('%s->%s', stations(floor(pos)+1).name, stations(ceil(pos)+1).name);
                end
                tempdata = [tempdata; {name, sprintf('%.0ft (%s)', locomotives(i).x, posInfo), locomotives(i).status,scheduleDeparture(1), S{1}, D{1}, S{2}, D{2}, S{3}, D{3}, S{4}, D{4}, S{5}, D{5}, scheduleArrival(7), D{6}}];
                % Load Figure
                offset = locomotives(i).x / 600 * 30;
                app.Icons(i).Position = [65+offset 185 23 23];
                app.Labels(i).Position = [67+offset 205 17 22];
                % Load Status 
                app.VelocityGauges(i).Value = locomotives(i).v;
                app.StatusLabels(i).Text = locomotives(i).status;
                app.XLabels(i).Text = sprintf('%s\n%.0ft', posInfo, locomotives(i).x);
                app.VLabels(i).Text = sprintf('%.1ft/s', locomotives(i).v);
                app.ALabels(i).Text = sprintf('%+.1ft/s^2', locomotives(i).a);
                switch locomotives(i).status
                    case 'Stopped'
                        app.StatusLamps(i).Color = [0.80,0.80,0.80];  % Gray
                    case 'Braking' 
                        app.StatusLamps(i).Color = [1.00,0.00,0.00];  % Red
                    otherwise
                        app.StatusLamps(i).Color = [0.00,1.00,0.00];  % Green
                end
                
            end
            app.TimeTable.Data = tempdata;
            % Load Station Rail Status
            for i = 0 : 6
                station = stations(i + 1);
                for j = 1 : station.numRails
                    if i >= 1 && i <=5
                       if station.currentRail == j
                            app.StationLamps{i}(j).Color = [0.00,1.00,0.00]; % Green
                       else
                            app.StationLamps{i}(j).Color = [0.80,0.80,0.80]; % Gray
                        end 
                    end
                    
                    if station.rails(j + 1) ~= 0
                        locomotive = locomotives(station.rails(j + 1));
                        app.Icons(locomotive.id).Position(1:2) = [app.Icons(locomotive.id).Position(1)+17 130-5-(j-1)*20];
                        app.Labels(locomotive.id).Position(1:2) = [app.Labels(locomotive.id).Position(1)+38 130-6-(j-1)*20];
                        if i == 0 || i == 6
                            app.Icons(locomotive.id).Position(1) = app.Icons(locomotive.id).Position(1) - 20;
                            app.Labels(locomotive.id).Position(1) = app.Labels(locomotive.id).Position(1) - 20;
                        end
                    end
                end
            end
        end
        function setRefreshRate(app, rate)
            app.RefreshRate = rate;
            if strcmp(app.DisplayButton.Icon, 'pause.png')
                stop(app.Controller.timer);
            end
            app.Controller.timer.Period = ceil(app.Controller.dt / rate / 0.001) * 0.001;
            if strcmp(app.DisplayButton.Icon, 'pause.png')
                start(app.Controller.timer);
            end
            app.DisplaySpeedMessage.Text = sprintf('Display Speed: %.1fx', rate);
        end
        function displayWarning(app, msg)
            app.WarningMessage.Text = msg;
            app.WarningMessage.FontColor = [1.00,0.00,0.00];
        end
        function displayMessage(app, msg)
            app.WarningMessage.Text = msg;
            app.WarningMessage.FontColor = [0.00,0.00,0.00];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Locomotive Control System';
            files = dir('schedules\*.csv');
            tempitems = {};
            for i = 1:length(files)
                tempitems = [tempitems; files(i).name];
            end
            app.RefreshRate = 1.0;
            app.ScheduleDropDown.Items = tempitems;
            app.ScheduleDropDown.Enable = 1;
            app.ImportButton.Enable = 1;
            app.ResetButton.Enable = 0;
            app.set1xButton.Enable = 0;
            app.set15xButton.Enable = 0;
            app.set2xButton.Enable = 0;
            app.set5xButton.Enable = 0;
            app.DisplayButton.Enable = 0;
            app.ForwardButton.Enable = 0;
            app.BackwardButton.Enable = 0;
            app.ClearButton.Enable = 0;
            app.PageDropDown.Visible = 0;
            % Create Manually Switch
            app.SwitchTo = [uimenu(app.StationSwitchContextMenu) uimenu(app.StationSwitchContextMenu) uimenu(app.StationSwitchContextMenu) uimenu(app.StationSwitchContextMenu)];
            app.S1Switch.UserData = 1;
            app.S2Switch.UserData = 2;
            app.S3Switch.UserData = 3;
            app.S4Switch.UserData = 4;
            app.S5Switch.UserData = 5;
            for i = 0 : 3
                app.SwitchTo(i+1).Text = sprintf('Switch to Rail%d', i);
                app.SwitchTo(i+1).UserData = i;
                app.SwitchTo(i+1).MenuSelectedFcn = createCallbackFcn(app, @SwitchToSelected, true);
            end
            app.SwitchTo(1).Text = 'Switch to Main';
            app.SwitchTo(1).Separator = 'on';
            app.StationLamps = {[uilamp(app.OverviewTab) uilamp(app.OverviewTab)];uilamp(app.OverviewTab);[uilamp(app.OverviewTab),uilamp(app.OverviewTab),uilamp(app.OverviewTab)];uilamp(app.OverviewTab);[uilamp(app.OverviewTab),uilamp(app.OverviewTab),uilamp(app.OverviewTab)]};
            % S1 Rail 2
            app.StationLamps{1}(1).Position = [310,130,10,10];
            app.StationLamps{1}(1).Color = [0.80,0.80,0.80];
            app.StationLamps{1}(2).Position = [310,110,10,10];
            app.StationLamps{1}(2).Color = [0.80,0.80,0.80];
            % S2 Rail 1
            app.StationLabels(2).Position = [449,105,85,37];
            app.StationLamps{2}(1).Position = [430,130,10,10];
            app.StationLamps{2}(1).Color = [0.80,0.80,0.80];
            % S3 Rail 3
            app.StationLabels(3).VerticalAlignment = 'top';
            app.StationLabels(3).FontSize = 16;
            app.StationLabels(3).Text = '';
            app.StationLabels(3).Position = [600,85,59,55];
            app.StationLamps{3}(1).Position = [580,130,10,10];
            app.StationLamps{3}(1).Color = [0.80,0.80,0.80];
            app.StationLamps{3}(2).Position = [580,110,10,10];
            app.StationLamps{3}(2).Color = [0.80,0.80,0.80];
            app.StationLamps{3}(3).Position = [580,90,10,10];
            app.StationLamps{3}(3).Color = [0.80,0.80,0.80];
            % S4 Rail 1
            app.StationLabels(4).VerticalAlignment = 'top';
            app.StationLabels(4).Text = '';
            app.StationLabels(4).FontSize = 16;
            app.StationLabels(4).Position = [687,105,75,37];
            app.StationLamps{4}(1).Position = [668,130,10,10];
            app.StationLamps{4}(1).Color = [0.80,0.80,0.80];
            % S5 Rail 3
            app.StationLabels(5).VerticalAlignment = 'top';
            app.StationLabels(5).Text = '';
            app.StationLabels(5).FontSize = 16;
            app.StationLabels(5).Position = [1049,85,66,55];
            app.StationLamps{5}(1).Position = [1030,130,10,10];
            app.StationLamps{5}(1).Color = [0.80,0.80,0.80];
            app.StationLamps{5}(1).UserData = [5, 1];
            app.StationLamps{5}(2).Position = [1030,110,10,10];
            app.StationLamps{5}(2).Color = [0.80,0.80,0.80];
            app.StationLamps{5}(3).Position = [1030,90,10,10];
            app.StationLamps{5}(3).Color = [0.80,0.80,0.80];
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            
            app.ImportButton.Enable = 0;
            app.ScheduleDropDown.Enable = 0;
            filename = ['schedules\' app.ScheduleDropDown.Value];
            try
                table = table2cell(readtable(filename));
            catch
                app.ScheduleDropDown.Enable = 1;
                app.ScheduleDropDown.BackgroundColor = [1.00,0.86,0.86];
                app.displayWarning('FILE NOT FOUND!');
                pause(1);
                app.displayMessage('System Runs normally');
                app.ScheduleDropDown.BackgroundColor = [1.00,1.00,1.00];
                app.ImportButton.Enable = 1;
                return;
            end
            app.TimeTable.Enable = 'on';
            app.Labels = [];
            app.Icons = [];
            app.LocomotivePanels = [];
            app.VelocityGauges = [];
            app.StatusLabels = [];
            app.StatusLamps = [];
            app.NameLabels = [];
            app.XLabels = [];
            app.VLabels = [];
            app.ALabels = [];
            xoffset = [0, 390, 780, 0, 390, 780];
            yoffset = [10, 10, 10, -275, -275, -275];
            for i = 1:size(table, 1)
                % Add to controller
                name = table{i, 1};
                for j = 3 : 7
                    if isnan(table{i, j})
                        table{i, j} = '';
                    end
                end
                S = {strsplit(table{i, 3},'/'); strsplit(table{i, 4},'/');strsplit(table{i, 5},'/');strsplit(table{i, 6},'/');strsplit(table{i, 7},'/')};
                scheduleArrival = [0 -1 -1 -1 -1 -1 table{i, 8}];
                scheduleDeparture = [table{i, 2} -1 -1 -1 -1 -1 99999];
                for j = 1:5
                    if ~isempty(S{j}{1})
                        scheduleArrival(j+1) = str2num(S{j}{1});
                        scheduleDeparture(j+1) =  str2num(S{j}{2});
                    end
                end
                locomotive = app.Controller.addLocomotive(i, name, scheduleArrival, scheduleDeparture);
                % Add icon
                app.Icons = [app.Icons uiimage(app.OverviewTab)];
                app.Icons(i).ImageSource = [name(1) '.png'];
                app.Icons(i).Position = [65 185 23 23];
                app.Icons(i).UserData = i;
                app.Icons(i).ImageClickedFcn = createCallbackFcn(app, @TrainClicked, true);
                % Add label
                app.Labels = [app.Labels uilabel(app.OverviewTab)];
                app.Labels(i).Text = name;
                app.Labels(i).Position = [67 205 17 22];
                app.Labels(i).HorizontalAlignment = 'center';
                switch name(1)
                    case 'K'
                        app.Labels(i).FontColor = [0.13,0.71,0.05];
                    case 'D'
                        app.Labels(i).FontColor = [0.0706 0.6196 1];
                    case 'G'
                        app.Labels(i).FontColor = [1 0 0];
                end
                % Add LocomotivePanels
                app.LocomotivePanels = [app.LocomotivePanels uipanel(app.LocomotiveHUDTab)];
                app.LocomotivePanels(i).Title = ['Locomotive #' int2str(i) ' ' name ' HUD'];
                app.LocomotivePanels(i).Position = [65+xoffset(mod(i - 1,6) + 1) 298+yoffset(mod(i - 1,6) + 1) 339 262];
                if i > 6
                    app.LocomotivePanels(i).Visible = 0;
                end
                % Add VelocityGauges
                app.VelocityGauges = [app.VelocityGauges uigauge(app.LocomotivePanels(i), 'semicircular')];
                app.VelocityGauges(i).Position = [55 53 233 126];
                app.VelocityGauges(i).Limits = [0 locomotive.v_max];
                % Add StatusLamps
                app.StatusLamps = [app.StatusLamps uilamp(app.LocomotivePanels(i))];
                app.StatusLamps(i).Position = [299 196 20 20];
                % Add StatusLabels
                app.StatusLabels = [app.StatusLabels uilabel(app.LocomotivePanels(i))];
                app.StatusLabels(i).HorizontalAlignment = 'right';
                app.StatusLabels(i).FontSize = 15;
                app.StatusLabels(i).Position = [208 195 90 20];
                % Add NameLabels
                app.NameLabels = [app.NameLabels uilabel(app.LocomotivePanels(i))];
                app.NameLabels(i).FontSize = 25;
                app.NameLabels(i).Position = [23 194 80 31];
                app.NameLabels(i).Text = name;
                % Add XLabels
                app.XLabels = [app.XLabels uilabel(app.LocomotivePanels(i))];
                app.XLabels(i).FontSize = 15;
                app.XLabels(i).Position = [22 159 130 38];
                % Add VLabels
                app.VLabels = [app.VLabels uilabel(app.LocomotivePanels(i))];
                app.VLabels(i).HorizontalAlignment = 'right';
                app.VLabels(i).FontSize = 25;
                app.VLabels(i).Position = [74 13 109 31];
                % Add ALabels
                app.ALabels = [app.ALabels uilabel(app.LocomotivePanels(i))];
                app.ALabels(i).FontSize = 15;
                app.ALabels(i).Position = [184 15 104 22];
                
            end
            app.loadUI;
            start(app.Controller.timer);
            app.DisplayButton.Icon = 'pause.png';
            app.DisplayButton.Tooltip = 'Pause';
            app.DisplayButton.Enable = 1;
            app.ForwardButton.Enable = 1;
            app.BackwardButton.Enable = 1;
            app.ClearButton.Enable = 1;
            app.ResetButton.Enable = 1;
            app.set1xButton.Enable = 1;
            app.set15xButton.Enable = 1;
            app.set2xButton.Enable = 1;
            app.set5xButton.Enable = 1;
            app.setRefreshRate(app.RefreshRate);
            app.PageDropDown.ItemsData = 1;
            tempitems = {};
            page = ceil(size(table, 1) / 6);
            for i = 1 : page
                tempitems = [tempitems sprintf('%d-%d', i*6-5, min(i*6, size(table, 1)))];
            end
            app.PageDropDown.Items = tempitems;
            app.PageDropDown.ItemsData = 1:page;
            app.PageDropDown.Value = 1;
            app.PageDropDown.Visible = 1;
        end

        % Clicked callback: DisplayButton
        function DisplayButtonClicked(app, event)
            switch app.DisplayButton.Icon
                case 'pause.png'
                    stop(app.Controller.timer);
                    app.DisplayButton.Icon = 'display.png';
                    app.DisplayButton.Tooltip = 'Continue';
                case 'display.png'
                    start(app.Controller.timer);
                    app.DisplayButton.Icon = 'pause.png';
                    app.DisplayButton.Tooltip = 'Pause';
            end
        end

        % Clicked callback: ForwardButton
        function ForwardButtonClicked(app, event)
            if app.RefreshRate < 3.5
                app.setRefreshRate(app.RefreshRate + 0.1);
            end
        end

        % Clicked callback: BackwardButton
        function BackwardButtonClicked(app, event)
            if app.RefreshRate > 0.1
                app.setRefreshRate(app.RefreshRate - 0.1);
            end
        end

        % Clicked callback: set5xButton
        function set5xButtonClicked(app, event)
            app.setRefreshRate(0.5);
        end

        % Clicked callback: set1xButton
        function set1xButtonClicked(app, event)
            app.setRefreshRate(1.0);
        end

        % Clicked callback: set15xButton
        function set15xButtonClicked(app, event)
            app.setRefreshRate(1.5);
        end

        % Clicked callback: set2xButton
        function set2xButtonClicked(app, event)
            app.setRefreshRate(2.0);
        end

        % Clicked callback: ClearButton
        function ClearButtonClicked(app, event)
            app.displayMessage('System Runs normally');
            app.Controller.clear;
            for i = 1:length(app.Labels)
                delete(app.Labels(i));
                delete(app.Icons(i));
                delete(app.LocomotivePanels(i));
                delete(app.VelocityGauges(i));
                delete(app.StatusLabels(i));
                delete(app.StatusLamps(i));
                delete(app.NameLabels(i));
                delete(app.XLabels(i));
                delete(app.VLabels(i));
                delete(app.ALabels(i));
            end
            app.TimeTable.Enable = 'off';
            app.PageDropDown.ItemsData = 1;
            app.ScheduleDropDown.Enable = 1;
            app.ImportButton.Enable = 1;
            app.DisplayButton.Enable = 0;
            app.DisplayButton.Icon = 'display.png';
            app.DisplayButton.Tooltip = 'Go';
            app.ForwardButton.Enable = 0;
            app.BackwardButton.Enable = 0;
            app.ClearButton.Enable = 0;
            app.ResetButton.Enable = 0;
            app.set1xButton.Enable = 0;
            app.set15xButton.Enable = 0;
            app.set2xButton.Enable = 0;
            app.set5xButton.Enable = 0;
            app.PageDropDown.Visible = 0;
            app.loadUI;
        end

        % Clicked callback: ResetButton
        function ResetButtonClicked(app, event)
            app.displayMessage('System Runs normally');
            app.Controller.reset;
            app.ScheduleDropDown.Enable = 0;
            app.ImportButton.Enable = 0;
            app.DisplayButton.Enable = 1;
            app.DisplayButton.Icon = 'display.png';
            app.DisplayButton.Tooltip = 'Go';
            app.ForwardButton.Enable = 1;
            app.BackwardButton.Enable = 1;
            app.ResetButton.Enable = 1;
            app.ClearButton.Enable = 1;
            app.set1xButton.Enable = 1;
            app.set15xButton.Enable = 1;
            app.set2xButton.Enable = 1;
            app.set5xButton.Enable = 1;
            app.loadUI;
        end

        % Value changed function: PageDropDown
        function PageDropDownValueChanged(app, event)
            value = app.PageDropDown.Value;
            num = size(app.TimeTable.Data, 1);
            pageStart = value*6-5;
            pageEnd = min(value*6, num);
            for i = 1:num
                app.LocomotivePanels(i).Visible = i >= pageStart && i <= pageEnd;
            end
        end

        % Image clicked function: S1Switch, S2Switch, S3Switch, 
        % S4Switch, S5Switch
        function StationClicked(app, event)
            id = event.Source.UserData;
            station = app.Controller.stationList(id+1);
            app.StationName.Text = [station.name ' Manual Switch'];
            for i = 0:station.numRails
                app.SwitchTo(i+1).Visible = 1;
                app.SwitchTo(i+1).Checked = i == station.currentRail && strcmp(station.mode, 'Manual');
            end
            for i = station.numRails+1:3
                app.SwitchTo(i+1).Visible = 0;
            end
            app.StationSwitchContextMenu.UserData = station;
            open(app.StationSwitchContextMenu, event.Source.Position(1) + 15, event.Source.Position(2) + 10);
        end

        % Callback function
        function SwitchToSelected(app, event)
            station = app.StationSwitchContextMenu.UserData;
            id = event.Source.UserData;
            if event.Source.Checked
                station.setMode('Auto');
            else
                station.setMode('Auto');
                station.switchToRail(id);
                station.setMode('Manual');
            end
        end

        % Callback function
        function TrainClicked(app, event)
            id = event.Source.UserData;
            locomotive = app.Controller.locomotiveList(id);
            app.StatusSwitchContextMenu.UserData = locomotive;
            app.TrainName.Text = [locomotive.name ' Manual Control'];
            switch locomotive.mode
                case 'Manual' 
                    if locomotive.targetV == locomotive.v_max 
                        app.SetMaxSpeed.Checked = 1;
                        app.SetCruising.Checked = 0;
                    else
                        app.SetMaxSpeed.Checked = 0;
                        app.SetCruising.Checked = 1;
                    end
                case 'Auto'
                    app.SetMaxSpeed.Checked = 0;
                    app.SetCruising.Checked = 0;
            end
            if locomotive.stationID == -1
                app.SetMaxSpeed.Enable = 1;
                app.SetCruising.Enable = 1;
            else
                app.SetMaxSpeed.Enable = 0;
                app.SetCruising.Enable = 0;
            end
            open(app.StatusSwitchContextMenu, event.Source.Position(1) + 20, event.Source.Position(2) - 20);
        end

        % Menu selected function: SetMaxSpeed
        function SetMaxSpeedMenuSelected(app, event)
            locomotive = app.StatusSwitchContextMenu.UserData;
            if app.SetMaxSpeed.Checked
                locomotive.setMode('Auto');
            else
                locomotive.setMode('Auto');
                locomotive.setTargetV(locomotive.v_max);
                locomotive.setMode('Manual');
            end
        end

        % Menu selected function: SetCruising
        function SetCruisingMenuSelected(app, event)
            locomotive = app.StatusSwitchContextMenu.UserData;
            if app.SetCruising.Checked
                locomotive.setMode('Auto');
            else
                locomotive.setMode('Auto');
                locomotive.setTargetV(locomotive.v_cruising);
                locomotive.setMode('Manual');
            end
        end

        % Menu selected function: StopAtNextStation
        function StopAtNextStationMenuSelected(app, event)
            locomotive = app.StatusSwitchContextMenu.UserData;
            locomotive.setMode('Auto');
            if locomotive.stationID ~= -1
                locomotive.nextStationID = locomotive.stationID + 1;
                if locomotive.departureTime(locomotive.stationID + 1) == -1
                    locomotive.departureTime(locomotive.stationID + 1) = 0;
                end
            else
                locomotive.nextStationID = ceil(app.Controller.getPosition(locomotive));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ClearButton = uipushtool(app.Toolbar);
            app.ClearButton.Tooltip = {'Clear Schedule'};
            app.ClearButton.ClickedCallback = createCallbackFcn(app, @ClearButtonClicked, true);
            app.ClearButton.Icon = 'Clear.png';
            app.ResetButton = uipushtool(app.Toolbar);
            app.ResetButton.Tooltip = {'Reset time'};
            app.ResetButton.ClickedCallback = createCallbackFcn(app, @ResetButtonClicked, true);
            app.ResetButton.Icon = 'Reset.png';
            app.BackwardButton = uipushtool(app.Toolbar);
            app.BackwardButton.Tooltip = {'-0.1x'};
            app.BackwardButton.ClickedCallback = createCallbackFcn(app, @BackwardButtonClicked, true);
            app.BackwardButton.Icon = 'backward.png';
            app.BackwardButton.Separator = 'on';
            app.set5xButton = uipushtool(app.Toolbar);
            app.set5xButton.Tooltip = {'Set to 0.5x'};
            app.set5xButton.ClickedCallback = createCallbackFcn(app, @set5xButtonClicked, true);
            app.set5xButton.Icon = '05x.png';
            app.set1xButton = uipushtool(app.Toolbar);
            app.set1xButton.Tooltip = {'Set to 1.0x'};
            app.set1xButton.ClickedCallback = createCallbackFcn(app, @set1xButtonClicked, true);
            app.set1xButton.Icon = '1x.png';
            app.set15xButton = uipushtool(app.Toolbar);
            app.set15xButton.Tooltip = {'Set to 1.5x'};
            app.set15xButton.ClickedCallback = createCallbackFcn(app, @set15xButtonClicked, true);
            app.set15xButton.Icon = '15x.png';
            app.set2xButton = uipushtool(app.Toolbar);
            app.set2xButton.Tooltip = {'Set to 2.0x'};
            app.set2xButton.ClickedCallback = createCallbackFcn(app, @set2xButtonClicked, true);
            app.set2xButton.Icon = '2x.png';
            app.ForwardButton = uipushtool(app.Toolbar);
            app.ForwardButton.Tooltip = {'+0.1x'};
            app.ForwardButton.ClickedCallback = createCallbackFcn(app, @ForwardButtonClicked, true);
            app.ForwardButton.Icon = 'forward.png';
            app.DisplayButton = uipushtool(app.Toolbar);
            app.DisplayButton.Tooltip = {'Pause'};
            app.DisplayButton.ClickedCallback = createCallbackFcn(app, @DisplayButtonClicked, true);
            app.DisplayButton.Icon = 'pause.png';
            app.DisplayButton.Separator = 'on';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.Image = uiimage(app.OverviewTab);
            app.Image.ImageSource = 'railway.png';
            app.OverviewInfoPanel = uipanel(app.OverviewTab);
            app.OverviewInfoPanel.Title = 'Overview Info';
            app.CurrentTimeMessage = uilabel(app.OverviewInfoPanel);
            app.CurrentTimeMessage.Text = 'Current Time: 0s';
            app.LocomotivesMessage = uilabel(app.OverviewInfoPanel);
            app.LocomotivesMessage.Text = '0 Locomotives in Control';
            app.StationsMessage = uilabel(app.OverviewInfoPanel);
            app.StationsMessage.Text = '7 Stations in Service';
            app.DisplaySpeedMessage = uilabel(app.OverviewInfoPanel);
            app.DisplaySpeedMessage.Text = 'Display Speed: 1.0x';
            app.WarningMessage = uilabel(app.OverviewInfoPanel);
            app.WarningMessage.Text = 'System Runs normally';
            app.TimeTable = uitable(app.OverviewTab);
            app.TimeTable.ColumnName = {'Train'; 'Position'; 'Status'; 'Start'; 'S1'; '+'; 'S2'; '+'; 'S3'; '+'; 'S4'; '+'; 'S5'; '+'; 'End'; '+'};
            app.TimeTable.RowName = {};
            app.ImportButton = uibutton(app.OverviewTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Icon = 'import.png';
            app.ImportButton.Text = '';
            app.ScheduleDropDown = uidropdown(app.OverviewTab);
            app.ScheduleDropDown.Items = {'sample.csv', 'schedule1.csv', 'schedule2.csv'};
            app.ScheduleDropDown.Value = 'sample.csv';
            app.S1Switch = uiimage(app.OverviewTab);
            app.S1Switch.ImageClickedFcn = createCallbackFcn(app, @StationClicked, true);
            app.S1Switch.ImageSource = 'station.png';
            app.S2Switch = uiimage(app.OverviewTab);
            app.S2Switch.ImageClickedFcn = createCallbackFcn(app, @StationClicked, true);
            app.S2Switch.ImageSource = 'station.png';
            app.S3Switch = uiimage(app.OverviewTab);
            app.S3Switch.ImageClickedFcn = createCallbackFcn(app, @StationClicked, true);
            app.S3Switch.ImageSource = 'station.png';
            app.S4Switch = uiimage(app.OverviewTab);
            app.S4Switch.ImageClickedFcn = createCallbackFcn(app, @StationClicked, true);
            app.S4Switch.ImageSource = 'station.png';
            app.S5Switch = uiimage(app.OverviewTab);
            app.S5Switch.ImageClickedFcn = createCallbackFcn(app, @StationClicked, true);
            app.S5Switch.ImageSource = 'station.png';
            app.LocomotiveHUDTab = uitab(app.TabGroup);
            app.LocomotiveHUDTab.Title = 'Locomotive HUD';
            app.PageDropDown = uidropdown(app.LocomotiveHUDTab);
            app.PageDropDown.Items = {'1-6', '7-10'};
            app.PageDropDown.ItemsData = {'1', '2', '3'};
            app.PageDropDown.ValueChangedFcn = createCallbackFcn(app, @PageDropDownValueChanged, true);
            app.PageDropDown.Value = '1';
            app.StatusSwitchContextMenu = uicontextmenu(app.UIFigure);
            app.TrainName = uimenu(app.StatusSwitchContextMenu);
            app.TrainName.Enable = 'off';
            app.TrainName.Text = 'D1 Manual';
            app.SetMaxSpeed = uimenu(app.StatusSwitchContextMenu);
            app.SetMaxSpeed.MenuSelectedFcn = createCallbackFcn(app, @SetMaxSpeedMenuSelected, true);
            app.SetMaxSpeed.Separator = 'on';
            app.SetMaxSpeed.Text = 'Set Max Speed';
            app.SetCruising = uimenu(app.StatusSwitchContextMenu);
            app.SetCruising.MenuSelectedFcn = createCallbackFcn(app, @SetCruisingMenuSelected, true);
            app.SetCruising.Text = 'Set Cruising';
            app.StopAtNextStation = uimenu(app.StatusSwitchContextMenu);
            app.StopAtNextStation.MenuSelectedFcn = createCallbackFcn(app, @StopAtNextStationMenuSelected, true);
            app.StopAtNextStation.Separator = 'on';
            app.StopAtNextStation.Text = 'Stop at next station';
            app.StationSwitchContextMenu = uicontextmenu(app.UIFigure);
            app.StationName = uimenu(app.StationSwitchContextMenu);
            app.StationName.Enable = 'off';
            app.StationName.Text = 'Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end